classdef appPBE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        SignalGenerationTab        matlab.ui.container.Tab
        RUNButton                  matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
        AmplitudeVEditFieldLabel   matlab.ui.control.Label
        AmplitudeVEditField        matlab.ui.control.NumericEditField
        freqHzEditFieldLabel       matlab.ui.control.Label
        freqHzEditField            matlab.ui.control.NumericEditField
        DurationsEditFieldLabel    matlab.ui.control.Label
        DurationsEditField         matlab.ui.control.NumericEditField
        SignalDropDownLabel        matlab.ui.control.Label
        SignalDropDown             matlab.ui.control.DropDown
        UIAxes2                    matlab.ui.control.UIAxes
        SamplefreqHzDropDownLabel  matlab.ui.control.Label
        SamplefreqHzDropDown       matlab.ui.control.DropDown
        freq2HzEditFieldLabel      matlab.ui.control.Label
        freq2HzEditField           matlab.ui.control.NumericEditField
        varEditFieldLabel          matlab.ui.control.Label
        varEditField               matlab.ui.control.NumericEditField
        SimulationTab_2            matlab.ui.container.Tab
        UIAxes3                    matlab.ui.control.UIAxes
        UIAxes3_2                  matlab.ui.control.UIAxes
        UIAxes3_3                  matlab.ui.control.UIAxes
        UIAxes3_4                  matlab.ui.control.UIAxes
        UIAxes3_5                  matlab.ui.control.UIAxes
        UIAxes3_6                  matlab.ui.control.UIAxes
        MeasurementTab             matlab.ui.container.Tab
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            opt = app.SignalDropDown.Value;
            
            A = app.AmplitudeVEditField.Value;
            fo = app.freqHzEditField.Value;
            f2 = app.freq2HzEditField.Value;
            D = app.DurationsEditField.Value;
            var = app.varEditField.Value;
            %fs = 44100;
            fs = str2double(app.SamplefreqHzDropDown.Value);
            t = 0:(1/fs):D-(1/fs);    
            n = 0:1:D*fs-(1/fs);
            
            function serra = dent_serra(A,fo,t)
                   serra = 2*(A*mod(fo*t,1)-A/2);
            end
            function tr = triangular(A,fo,t)
                tr = 2*abs(dent_serra(A,fo,t))-A;
            end
            function s = sinusoide(A,fo,t)
                s = A*sin(2*pi*t*fo);
            end
            function tren = polsos(A,fo,t)
                a = sinusoide(A,fo,t);
                for i = 1: length(a)
                    if(a(i)>0)
                        a(i) = 1;
                    else
                        a(i)=-1;
                    end
                end
                tren = a;
            end
            
            function s = soroll(var,t)
                s = randn(var,length(t));
            end
            
            function c = chirp(f1,f2,A,t)
                frec = linspace(f1,f2,length(t));
                c = A*sin(2*pi*frec.*t);
            end
            
        
            switch opt
                case '1'
                    x = sinusoide(A,fo,t);
                  
                case '2'
                    x = dent_serra(A,fo,t);
                    
                case '3'
                    x = triangular(A,fo,t);
                    
                case '4'
                    x = polsos(A,fo,t);
                    
                case '5'
                    x = soroll(var,t);
                    
                case '6'
                    x = chirp(fo,f2,A,t);
            end
            
            plot(app.UIAxes, t, x)
            X = fft(x);
            plot(app.UIAxes2, t, abs(X))
        end

        % Value changed function: SignalDropDown
        function SignalDropDownValueChanged(app, event)
            value = app.SignalDropDown.Value;
            switch value
                case '5'
                    app.varEditField.Editable = true;
                    app.varEditField.Enable = true;
                    app.varEditField.Visible = true;
                    app.varEditFieldLabel.Visible  = true;
                    app.freq2HzEditField.Editable = false;
                    app.freq2HzEditField.Enable = false;
                    app.freq2HzEditField.Visible = false;
                    app.freq2HzEditFieldLabel.Visible = false;
                    app.freqHzEditField.Editable = false;
                    app.freqHzEditField.Enable = false;
                    app.freqHzEditField.Visible = false;
                    app.freqHzEditFieldLabel.Visible = false;
                case '6'
                    app.freq2HzEditField.Editable = true;
                    app.freq2HzEditField.Enable = true;
                    app.freq2HzEditField.Visible = true;
                    app.freq2HzEditFieldLabel.Visible = true;
                    app.freqHzEditField.Editable = true;
                    app.freqHzEditField.Enable = true;
                    app.freqHzEditField.Visible = true;
                    app.freqHzEditFieldLabel.Visible = true;
                    app.varEditField.Editable = false;
                    app.varEditField.Enable = false;
                    app.varEditField.Visible = false;
                    app.varEditFieldLabel.Visible  = false;
                otherwise
                    app.varEditField.Editable = false;
                    app.varEditField.Enable = false;
                    app.varEditField.Visible = false;
                    app.varEditFieldLabel.Visible  = false;
                    app.freq2HzEditField.Editable = false;
                    app.freq2HzEditField.Enable = false;
                    app.freq2HzEditField.Visible = false;
                    app.freq2HzEditFieldLabel.Visible = false;
                    app.freqHzEditField.Editable = true;
                    app.freqHzEditField.Enable = true;
                    app.freqHzEditField.Visible = true;
                    app.freqHzEditFieldLabel.Visible = true;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SignalGenerationTab = uitab(app.TabGroup);
            app.SignalGenerationTab.Title = 'Signal Generation';
            app.RUNButton = uibutton(app.SignalGenerationTab, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.UIAxes = uiaxes(app.SignalGenerationTab);
            title(app.UIAxes, 'Signal')
            xlabel(app.UIAxes, 't(s)')
            ylabel(app.UIAxes, 'A(V)')
            app.AmplitudeVEditFieldLabel = uilabel(app.SignalGenerationTab);
            app.AmplitudeVEditFieldLabel.Text = 'Amplitude (V)';
            app.AmplitudeVEditField = uieditfield(app.SignalGenerationTab, 'numeric');
            app.AmplitudeVEditField.Limits = [0 1];
            app.AmplitudeVEditField.Value = 1;
            app.freqHzEditFieldLabel = uilabel(app.SignalGenerationTab);
            app.freqHzEditFieldLabel.Text = 'freq (Hz)';
            app.freqHzEditField = uieditfield(app.SignalGenerationTab, 'numeric');
            app.freqHzEditField.Limits = [0 Inf];
            app.freqHzEditField.Value = 10;
            app.DurationsEditFieldLabel = uilabel(app.SignalGenerationTab);
            app.DurationsEditFieldLabel.Text = 'Duration (s)';
            app.DurationsEditField = uieditfield(app.SignalGenerationTab, 'numeric');
            app.DurationsEditField.Limits = [0 Inf];
            app.DurationsEditField.Value = 0.5;
            app.SignalDropDownLabel = uilabel(app.SignalGenerationTab);
            app.SignalDropDownLabel.Text = 'Signal';
            app.SignalDropDown = uidropdown(app.SignalGenerationTab);
            app.SignalDropDown.Items = {'sinusoide', 'saw teeth', 'triangular', 'rectangular', 'noise', 'chirp'};
            app.SignalDropDown.ItemsData = {'1', '2', '3', '4', '5', '6'};
            app.SignalDropDown.ValueChangedFcn = createCallbackFcn(app, @SignalDropDownValueChanged, true);
            app.SignalDropDown.Value = '1';
            app.UIAxes2 = uiaxes(app.SignalGenerationTab);
            title(app.UIAxes2, 'F.T.')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.SamplefreqHzDropDownLabel = uilabel(app.SignalGenerationTab);
            app.SamplefreqHzDropDownLabel.Text = 'Sample freq (Hz)';
            app.SamplefreqHzDropDown = uidropdown(app.SignalGenerationTab);
            app.SamplefreqHzDropDown.Items = {'8000', '44100', '48000', '96000', '192000'};
            app.SamplefreqHzDropDown.Value = '8000';
            app.freq2HzEditFieldLabel = uilabel(app.SignalGenerationTab);
            app.freq2HzEditFieldLabel.Visible = 'off';
            app.freq2HzEditFieldLabel.Text = 'freq2 (Hz)';
            app.freq2HzEditField = uieditfield(app.SignalGenerationTab, 'numeric');
            app.freq2HzEditField.Limits = [0 Inf];
            app.freq2HzEditField.Editable = 'off';
            app.freq2HzEditField.Visible = 'off';
            app.freq2HzEditField.Value = 10;
            app.varEditFieldLabel = uilabel(app.SignalGenerationTab);
            app.varEditFieldLabel.Visible = 'off';
            app.varEditFieldLabel.Text = 'var';
            app.varEditField = uieditfield(app.SignalGenerationTab, 'numeric');
            app.varEditField.LowerLimitInclusive = 'off';
            app.varEditField.Limits = [0 Inf];
            app.varEditField.Editable = 'off';
            app.varEditField.Visible = 'off';
            app.varEditField.Value = 1;
            app.SimulationTab_2 = uitab(app.TabGroup);
            app.SimulationTab_2.Title = 'Simulation';
            app.UIAxes3 = uiaxes(app.SimulationTab_2);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 't(s)')
            ylabel(app.UIAxes3, 'V')
            app.UIAxes3_2 = uiaxes(app.SimulationTab_2);
            title(app.UIAxes3_2, 'Title')
            xlabel(app.UIAxes3_2, 't(s)')
            ylabel(app.UIAxes3_2, 'V')
            app.UIAxes3_3 = uiaxes(app.SimulationTab_2);
            title(app.UIAxes3_3, 'Title')
            xlabel(app.UIAxes3_3, 't(s)')
            ylabel(app.UIAxes3_3, 'V')
            app.UIAxes3_4 = uiaxes(app.SimulationTab_2);
            title(app.UIAxes3_4, 'Title')
            xlabel(app.UIAxes3_4, 't(s)')
            ylabel(app.UIAxes3_4, 'V')
            app.UIAxes3_5 = uiaxes(app.SimulationTab_2);
            title(app.UIAxes3_5, 'Title')
            xlabel(app.UIAxes3_5, 't(s)')
            ylabel(app.UIAxes3_5, 'V')
            app.UIAxes3_6 = uiaxes(app.SimulationTab_2);
            title(app.UIAxes3_6, 'Title')
            xlabel(app.UIAxes3_6, 't(s)')
            ylabel(app.UIAxes3_6, 'V')
            app.MeasurementTab = uitab(app.TabGroup);
            app.MeasurementTab.Title = 'Measurement';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appPBE

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end